classdef FRONT_END < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlesPanel             matlab.ui.container.Panel
        OpcionesdeGraficasPanel    matlab.ui.container.Panel
        DropDown_4                 matlab.ui.control.DropDown
        DropDown_3                 matlab.ui.control.DropDown
        DropDown_2                 matlab.ui.control.DropDown
        EspectroLabel              matlab.ui.control.Label
        EjefreqLabel               matlab.ui.control.Label
        MagnitudLabel              matlab.ui.control.Label
        ExportarComunicacionPanel  matlab.ui.container.Panel
        EnviarfiltroaNucleoButton  matlab.ui.control.Button
        ExportarmatButton          matlab.ui.control.Button
        ExportarcsvButton          matlab.ui.control.Button
        FiltroEfectoPanel          matlab.ui.container.Panel
        STOPButton                 matlab.ui.control.Button
        AudioprocesadoButton       matlab.ui.control.Button
        AudiooriginalButton        matlab.ui.control.Button
        AproximacinLabel           matlab.ui.control.Label
        DropDown_5                 matlab.ui.control.DropDown
        APLICARButton              matlab.ui.control.Button
        EditField5                 matlab.ui.control.NumericEditField
        EditField4                 matlab.ui.control.NumericEditField
        EditField3                 matlab.ui.control.NumericEditField
        EditField2_3               matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        UmbralLabel                matlab.ui.control.Label
        RepeticionesLabel          matlab.ui.control.Label
        GananciaLabel              matlab.ui.control.Label
        DelaysLabel                matlab.ui.control.Label
        fc2HzLabel                 matlab.ui.control.Label
        EditField2_2               matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        DropDown2                  matlab.ui.control.DropDown
        DropDown                   matlab.ui.control.DropDown
        fc1HzLabel                 matlab.ui.control.Label
        OrdenLabel                 matlab.ui.control.Label
        ClaseLabel                 matlab.ui.control.Label
        TipoLabel                  matlab.ui.control.Label
        PuertoserialPanel          matlab.ui.container.Panel
        EditField2                 matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.EditField
        BaudLabel                  matlab.ui.control.Label
        PuertoLabel                matlab.ui.control.Label
        SenaldeentradaPanel        matlab.ui.container.Panel
        SinsenalcargadaLabel       matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        NmuestrasLabel             matlab.ui.control.Label
        CapturarNucleoButton       matlab.ui.control.Button
        CargarmatButton            matlab.ui.control.Button
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        senal           % señal original
        senal_proc      % señal procesada
        Fs              % frecuencia de muestreo
        b_coef          % coeficientes b del filtro
        a_coef          % coeficientes a del filtro
        s_port          % objeto puerto serial
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
   
           
            [file, path] = uigetfile({'*.mat;*.wav;*.mp3;*.flac;*.ogg', 'Archivos soportados'}, 'Seleccionar archivo');
            if isequal(file, 0)
                return;
            end
            
            ruta = fullfile(path, file);
            [~, ~, ext] = fileparts(file);
            
            if strcmp(ext, '.mat')
                datos = load(ruta);
                if isfield(datos, 'x')
                    app.senal = datos.x(:);
                elseif isfield(datos, 'senal')
                    app.senal = datos.senal(:);
                else
                    campos = fieldnames(datos);
                    app.senal = datos.(campos{1});
                    app.senal = app.senal(:);
                end
                if isfield(datos, 'Fs')
                    app.Fs = datos.Fs;
                elseif isfield(datos, 'fs')
                    app.Fs = datos.fs;
                else
                    app.Fs = 8000;
                    uialert(app.UIFigure, 'No se encontro Fs, se usara 8000 Hz', 'Aviso');
                end
            else
                [audio, fs] = audioread(ruta);
                if size(audio, 2) > 1
                    audio = mean(audio, 2);
                end
                app.senal = audio(:);
                app.Fs = fs;
            end
            
            app.senal_proc = [];
            app.SinsenalcargadaLabel.Text = sprintf('N=%d | Fs=%d Hz | T=%.3f s', length(app.senal), app.Fs, length(app.senal)/app.Fs);
            
            t = (0:length(app.senal)-1) / app.Fs;
            plot(app.UIAxes, t, app.senal, 'b');
            title(app.UIAxes, 'Senal Original');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on');
            
            [f, P1] = calcular_espectro(app.senal, app.Fs);
            plot(app.UIAxes2, f, P1, 'Color', [0 0.45 0.74]);
            title(app.UIAxes2, 'Espectro Original');
            xlabel(app.UIAxes2, 'Frecuencia (Hz)');
            ylabel(app.UIAxes2, 'Amplitud');
            grid(app.UIAxes2, 'on');

        end

        % Button pushed function: CapturarNucleoButton
        function CapturarNucleoButtonPushed(app, event)
  
            try
                puerto = app.EditField_2.Value;
                baud = app.EditField2.Value;
                N = app.EditField.Value;
         
                if ~isempty(app.s_port)
                    if isvalid(app.s_port)
                        delete(app.s_port);
                    end
                end
         
                % 1. Abrimos el puerto
                app.s_port = serialport(puerto, baud);
                
                % ---> AQUÍ VAN LOS CAMBIOS <---
                % 2. Aumentamos el Timeout a 30 segundos
                app.s_port.Timeout = 30; 
                configureTerminator(app.s_port, "LF");
                
                % 3. PAUSA VITAL para que la Nucleo se recupere del reinicio USB
                pause(1.5); 
                % ------------------------------

                % 4. Limpiamos y enviamos el comando
                flush(app.s_port);
                writeline(app.s_port, sprintf("CAPTURE %d", N));
         
                datos_raw = zeros(N, 1);
                for i = 1:N
                    linea = readline(app.s_port);
                    datos_raw(i) = str2double(linea);
                end
         
                app.senal = (datos_raw / 4095) * 3.3;
                app.senal = app.senal - mean(app.senal);
                app.Fs = 8000;
                app.senal_proc = [];
                app.SinsenalcargadaLabel.Text = sprintf('Capturada: N=%d | Fs=%d Hz', N, app.Fs);
         
                t = (0:length(app.senal)-1) / app.Fs;
                plot(app.UIAxes, t, app.senal, 'b');
                title(app.UIAxes, 'Senal Original');
                xlabel(app.UIAxes, 'Tiempo (s)');
                ylabel(app.UIAxes, 'Amplitud');
                grid(app.UIAxes, 'on');
         
                [f, P1] = calcular_espectro(app.senal, app.Fs);
                plot(app.UIAxes2, f, P1, 'Color', [0 0.45 0.74]);
                title(app.UIAxes2, 'Espectro Original');
                xlabel(app.UIAxes2, 'Frecuencia (Hz)');
                ylabel(app.UIAxes2, 'Amplitud');
                grid(app.UIAxes2, 'on');
         
                delete(app.s_port);
                app.s_port = [];
         
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Serial');
                if ~isempty(app.s_port)
                    if isvalid(app.s_port)
                        delete(app.s_port);
                    end
                end
                app.s_port = [];
            end
        
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            tipo = strtrim(app.DropDown.Value);
 
                app.DropDown2.Visible = 'off';
                app.ClaseLabel.Visible = 'off';
                app.EditField_3.Visible = 'off';
                app.OrdenLabel.Visible = 'off';
                app.EditField2_2.Visible = 'off';
                app.fc1HzLabel.Visible = 'off';
                app.EditField3.Visible = 'off';
                app.fc2HzLabel.Visible = 'off';
                app.EditField4.Visible = 'off';
                app.DelaysLabel.Visible = 'off';
                app.EditField_4.Visible = 'off';            
                app.GananciaLabel.Visible = 'off';
                app.EditField2_3.Visible = 'off';
                app.RepeticionesLabel.Visible = 'off';
                app.EditField5.Visible = 'off';
                app.UmbralLabel.Visible = 'off';
                app.DropDown_5.Visible = 'off';
                app.AproximacinLabel.Visible = 'off';
             
                switch tipo
                    case 'Filtro IIR'
                        app.DropDown2.Visible = 'on';
                        app.ClaseLabel.Visible = 'on';
                        app.EditField_3.Visible = 'on';
                        app.OrdenLabel.Visible = 'on';
                        app.EditField2_2.Visible = 'on';
                        app.fc1HzLabel.Visible = 'on';
                        app.DropDown_5.Visible = 'on';
                        app.AproximacinLabel.Visible = 'on'; 
                        app.DropDown_5.Items = {'Butterworth', 'Chebyshev'};
                        app.DropDown_5.Value = 'Butterworth';
                        clase = app.DropDown2.Value;
                        if strcmp(clase, 'BANDPASS') || strcmp(clase, 'BANDSTOP')
                            app.EditField3.Visible = 'on';
                            app.fc2HzLabel.Visible = 'on';
                            
                        end
             
                    case 'Filtro FIR'
                        app.DropDown2.Visible = 'on';
                        app.ClaseLabel.Visible = 'on';
                        app.EditField_3.Visible = 'on';
                        app.OrdenLabel.Visible = 'on';
                        app.EditField2_2.Visible = 'on';
                        app.fc1HzLabel.Visible = 'on';
                        app.DropDown_5.Visible = 'on';
                        app.AproximacinLabel.Visible = 'on';
                        app.DropDown_5.Items = {'Damped', 'LS'};
                        app.DropDown_5.Value = 'Damped';
                        clase = app.DropDown2.Value;
                        if strcmp(clase, 'BANDPASS') || strcmp(clase, 'BANDSTOP')
                            app.EditField3.Visible = 'on';
                            app.fc2HzLabel.Visible = 'on';
                        end
             
                    case 'Eco'
                        app.EditField4.Visible = 'on';
                        app.DelaysLabel.Visible = 'on';
                        app.EditField_4.Visible = 'on';
                        app.GananciaLabel.Visible = 'on';
                        app.EditField2_3.Visible = 'on';
                        app.RepeticionesLabel.Visible = 'on';
             
                    case 'Distorsion'
                        app.EditField_4.Visible = 'on';
                        app.GananciaLabel.Visible = 'on';
                        app.EditField5.Visible = 'on';
                        app.UmbralLabel.Visible = 'on';
                end
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            clase = app.DropDown2.Value;
            if strcmp(clase, 'BANDPASS') || strcmp(clase, 'BANDSTOP')
                app.EditField3.Visible = 'on';
                app.fc2HzLabel.Visible = 'on';
            else
                app.EditField3.Visible = 'off';
                app.fc2HzLabel.Visible = 'off';
            end
        end

        % Button pushed function: APLICARButton
        function APLICARButtonPushed(app, event)
            if isempty(app.senal)
                uialert(app.UIFigure, 'Cargue o capture una senal primero', 'Sin senal');
                return;
            end
         
            tipo = strtrim(app.DropDown.Value);
         
            switch tipo
                case 'Filtro IIR'
                    tf = "IIR";
                    clase = lower(app.DropDown2.Value);
                    orden = app.EditField_3.Value;
                    fc1 = app.EditField2_2.Value;
                    fc2 = app.EditField3.Value;
         
                    if fc1 >= app.Fs/2
                        uialert(app.UIFigure, 'fc1 debe ser menor que Fs/2', 'Error');
                        return;
                    end
         
                    if strcmp(clase, 'bandpass') || strcmp(clase, 'bandstop')
                        if fc2 <= fc1 || fc2 >= app.Fs/2
                            uialert(app.UIFigure, 'fc2 debe ser > fc1 y < Fs/2', 'Error');
                            return;
                        end
                        metodo = lower(app.DropDown_5.Value);
                        [app.b_coef, app.a_coef] = disenar_filtro(tf, clase, app.Fs, orden, fc1, fc2, metodo);
                    else
                        metodo = lower(app.DropDown_5.Value);
                        [app.b_coef, app.a_coef] = disenar_filtro(tf, clase, app.Fs, orden, fc1, [], metodo);
                    end
         
                    params.b = app.b_coef;
                    params.a = app.a_coef;
                    app.senal_proc = aplicar_efecto(app.senal, app.Fs, "filtro", params);
         
                    [w_rad, f_hz, H_mag, H_dB, H_fase] = resp_frecuencia(app.b_coef, app.a_coef, app.Fs);
         
                    switch app.DropDown_3.Value
                        case 'Hz'
                            xf = f_hz;
                            xl_txt = 'Frecuencia (Hz)';
                        case 'rad/muestra'
                            xf = w_rad;
                            xl_txt = 'Frecuencia (rad/muestra)';
                    end
         
                    switch app.DropDown_2.Value
                        case 'dB'
                            ym = H_dB;
                            yl_txt = 'Magnitud (dB)';
                        case 'Lineal'
                            ym = H_mag;
                            yl_txt = 'Magnitud';
                    end
         
                    plot(app.UIAxes5, xf, ym, 'b', 'LineWidth', 1.2);
                    title(app.UIAxes5, 'Resp. Frecuencia - Magnitud');
                    xlabel(app.UIAxes5, xl_txt);
                    ylabel(app.UIAxes5, yl_txt);
                    grid(app.UIAxes5, 'on');
         
                    plot(app.UIAxes4, xf, H_fase, 'r', 'LineWidth', 1.2);
                    title(app.UIAxes4, 'Resp. Frecuencia - Fase');
                    xlabel(app.UIAxes4, xl_txt);
                    ylabel(app.UIAxes4, 'Fase (grados)');
                    grid(app.UIAxes4, 'on');
         
                case 'Filtro FIR'
                    tf = "FIR";
                    clase = lower(app.DropDown2.Value);
                    orden = app.EditField_3.Value;
                    fc1 = app.EditField2_2.Value;
                    fc2 = app.EditField3.Value;
         
                    if fc1 >= app.Fs/2
                        uialert(app.UIFigure, 'fc1 debe ser menor que Fs/2', 'Error');
                        return;
                    end
         
                    if strcmp(clase, 'bandpass') || strcmp(clase, 'bandstop')
                        if fc2 <= fc1 || fc2 >= app.Fs/2
                            uialert(app.UIFigure, 'fc2 debe ser > fc1 y < Fs/2', 'Error');
                            return;
                        end
                        metodo = lower(app.DropDown_5.Value);
                        [app.b_coef, app.a_coef] = disenar_filtro(tf, clase, app.Fs, orden, fc1, fc2, metodo);
                    else
                        metodo = lower(app.DropDown_5.Value);
                        [app.b_coef, app.a_coef] = disenar_filtro(tf, clase, app.Fs, orden, fc1, [], metodo);
                    end
         
                    params.b = app.b_coef;
                    params.a = app.a_coef;
                    app.senal_proc = aplicar_efecto(app.senal, app.Fs, "filtro", params);
         
                    [w_rad, f_hz, H_mag, H_dB, H_fase] = resp_frecuencia(app.b_coef, app.a_coef, app.Fs);
         
                    switch app.DropDown_3.Value
                        case 'Hz'
                            xf = f_hz;
                            xl_txt = 'Frecuencia (Hz)';
                        case 'rad/muestra'
                            xf = w_rad;
                            xl_txt = 'Frecuencia (rad/muestra)';
                    end
         
                    switch app.DropDown_2.Value
                        case 'dB'
                            ym = H_dB;
                            yl_txt = 'Magnitud (dB)';
                        case 'Lineal'
                            ym = H_mag;
                            yl_txt = 'Magnitud';
                    end
         
                    plot(app.UIAxes5, xf, ym, 'b', 'LineWidth', 1.2);
                    title(app.UIAxes5, 'Resp. Frecuencia - Magnitud');
                    xlabel(app.UIAxes5, xl_txt);
                    ylabel(app.UIAxes5, yl_txt);
                    grid(app.UIAxes5, 'on');
         
                    plot(app.UIAxes4, xf, H_fase, 'r', 'LineWidth', 1.2);
                    title(app.UIAxes4, 'Resp. Frecuencia - Fase');
                    xlabel(app.UIAxes4, xl_txt);
                    ylabel(app.UIAxes4, 'Fase (grados)');
                    grid(app.UIAxes4, 'on');
         
                case 'Eco'
                    params.delay = app.EditField4.Value;
                    params.ganancia = app.EditField_4.Value;
                    params.repeticiones = app.EditField2_3.Value;
                    app.senal_proc = aplicar_efecto(app.senal, app.Fs, "eco", params);
                    app.b_coef = [];
                    app.a_coef = [];
                    cla(app.UIAxes5);
                    title(app.UIAxes5, 'N/A para eco');
                    cla(app.UIAxes4);
                    title(app.UIAxes4, 'N/A para eco');
         
                case 'Distorsion'
                    params.ganancia = app.EditField_4.Value;
                    params.umbral = app.EditField5.Value;
                    app.senal_proc = aplicar_efecto(app.senal, app.Fs, "distorsion", params);
                    app.b_coef = [];
                    app.a_coef = [];
                    cla(app.UIAxes5);
                    title(app.UIAxes5, 'N/A para distorsion');
                    cla(app.UIAxes4);
                    title(app.UIAxes4, 'N/A para distorsion');
            end
         
            t = (0:length(app.senal_proc)-1) / app.Fs;
            plot(app.UIAxes3, t, app.senal_proc, 'r');
            title(app.UIAxes3, 'Senal Procesada');
            xlabel(app.UIAxes3, 'Tiempo (s)');
            ylabel(app.UIAxes3, 'Amplitud');
            grid(app.UIAxes3, 'on');
         
            [f2, P2] = calcular_espectro(app.senal_proc, app.Fs);
            switch app.DropDown_4.Value
                case 'plot'
                    plot(app.UIAxes6, f2, P2, 'Color', [0.85 0.33 0.10]);
                case 'stem'
                    stem(app.UIAxes6, f2, P2, 'Color', [0.85 0.33 0.10], 'MarkerSize', 2);
            end
            title(app.UIAxes6, 'Espectro Procesado');
            xlabel(app.UIAxes6, 'Frecuencia (Hz)');
            ylabel(app.UIAxes6, 'Amplitud');
            grid(app.UIAxes6, 'on');
        end

        % Value changed function: DropDown_2, DropDown_3, DropDown_4
        function DropDown_2ValueChanged(app, event)

            if ~isempty(app.b_coef)
                [w_rad, f_hz, H_mag, H_dB, H_fase] = resp_frecuencia(app.b_coef, app.a_coef, app.Fs);
         
                switch app.DropDown_3.Value
                    case 'Hz'
                        xf = f_hz;
                        xl_txt = 'Frecuencia (Hz)';
                    case 'rad/muestra'
                        xf = w_rad;
                        xl_txt = 'Frecuencia (rad/muestra)';
                end
         
                switch app.DropDown_2.Value
                    case 'dB'
                        ym = H_dB;
                        yl_txt = 'Magnitud (dB)';
                    case 'Lineal'
                        ym = H_mag;
                        yl_txt = 'Magnitud';
                end
         
                plot(app.UIAxes5, xf, ym, 'b', 'LineWidth', 1.2);
                title(app.UIAxes5, 'Resp. Frecuencia - Magnitud');
                xlabel(app.UIAxes5, xl_txt);
                ylabel(app.UIAxes5, yl_txt);
                grid(app.UIAxes5, 'on');
         
                plot(app.UIAxes4, xf, H_fase, 'r', 'LineWidth', 1.2);
                title(app.UIAxes4, 'Resp. Frecuencia - Fase');
                xlabel(app.UIAxes4, xl_txt);
                ylabel(app.UIAxes4, 'Fase (grados)');
                grid(app.UIAxes4, 'on');
            end
         
            if ~isempty(app.senal)
                [f, P1] = calcular_espectro(app.senal, app.Fs);
                switch app.DropDown_4.Value
                    case 'plot'
                        plot(app.UIAxes2, f, P1, 'Color', [0 0.45 0.74]);
                    case 'stem'
                        stem(app.UIAxes2, f, P1, 'Color', [0 0.45 0.74], 'MarkerSize', 2);
                end
                title(app.UIAxes2, 'Espectro Original');
                xlabel(app.UIAxes2, 'Frecuencia (Hz)');
                ylabel(app.UIAxes2, 'Amplitud');
                grid(app.UIAxes2, 'on');
            end
         
            if ~isempty(app.senal_proc)
                [f2, P2] = calcular_espectro(app.senal_proc, app.Fs);
                switch app.DropDown_4.Value
                    case 'plot'
                        plot(app.UIAxes6, f2, P2, 'Color', [0.85 0.33 0.10]);
                    case 'stem'
                        stem(app.UIAxes6, f2, P2, 'Color', [0.85 0.33 0.10], 'MarkerSize', 2);
                end
                title(app.UIAxes6, 'Espectro Procesado');
                xlabel(app.UIAxes6, 'Frecuencia (Hz)');
                ylabel(app.UIAxes6, 'Amplitud');
                grid(app.UIAxes6, 'on');
            end
        end

        % Button pushed function: ExportarcsvButton
        function ExportarcsvButtonPushed(app, event)
           if isempty(app.senal)
            uialert(app.UIFigure, 'No hay senal', 'Sin datos');
            return;
           end
     
            [file, path] = uiputfile('*.csv', 'Guardar CSV');
            if isequal(file, 0)
                return;
            end
         
            N_o = length(app.senal);
         
            if ~isempty(app.senal_proc)
                N_p = length(app.senal_proc);
                N_max = max(N_o, N_p);
                t = (0:N_max-1)' / app.Fs;
                col_o = [app.senal; NaN(N_max - N_o, 1)];
                col_p = [app.senal_proc; NaN(N_max - N_p, 1)];
                T = table(t, col_o, col_p, 'VariableNames', {'Tiempo_s', 'Senal_Original', 'Senal_Procesada'});
            else
                t = (0:N_o-1)' / app.Fs;
                T = table(t, app.senal, 'VariableNames', {'Tiempo_s', 'Senal_Original'});
            end
         
            writetable(T, fullfile(path, file));
            uialert(app.UIFigure, ['Guardado: ' file], 'CSV', 'Icon', 'success');
        end

        % Button pushed function: ExportarmatButton
        function ExportarmatButtonPushed(app, event)
            if isempty(app.senal)
                uialert(app.UIFigure, 'No hay datos', 'Sin datos');
                return;
            end
         
            [file, path] = uiputfile('*.mat', 'Guardar .mat');
            if isequal(file, 0)
                return;
            end
         
            d.x = app.senal;
            d.Fs = app.Fs;
         
            if ~isempty(app.senal_proc)
                d.x_procesada = app.senal_proc;
            end
         
            if ~isempty(app.b_coef)
                d.b = app.b_coef;
                d.a = app.a_coef;
            end
         
            save(fullfile(path, file), '-struct', 'd');
            uialert(app.UIFigure, ['Guardado: ' file], 'MAT', 'Icon', 'success');
        end

        % Button pushed function: EnviarfiltroaNucleoButton
        function EnviarfiltroaNucleoButtonPushed(app, event)
            if isempty(app.b_coef)
                uialert(app.UIFigure, 'Disene un filtro primero', 'Sin filtro');
                return;
            end
            
            try
                puerto = app.EditField_2.Value;
                baud = app.EditField2.Value;
            
                if ~isempty(app.s_port)
                    if isvalid(app.s_port)
                        delete(app.s_port);
                    end
                end
            
                %convertir a secciones de segundo ordend
                [sos, g] = tf2sos(app.b_coef, app.a_coef);
                n_sec = size(sos, 1);
            
                app.s_port = serialport(puerto, baud);
                configureTerminator(app.s_port, "LF");
                pause(0.5);
            
                %mandar comando con numero de secciones y ganancia
                writeline(app.s_port, sprintf("SOS %d", n_sec));
                pause(0.05);
                writeline(app.s_port, sprintf("%.10f", g));
                pause(0.01);
            
                %cada seccion son 6 numeros: b0 b1 b2 a0 a1 a2
                for i = 1:n_sec
                    for j = 1:6
                        writeline(app.s_port, sprintf("%.10f", sos(i,j)));
                        pause(0.01);
                    end
                end
            
                delete(app.s_port);
                app.s_port = [];
                uialert(app.UIFigure, sprintf('Enviadas %d secciones', n_sec), 'Exito', 'Icon', 'success');
            
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Serial');
                if ~isempty(app.s_port)
                    if isvalid(app.s_port)
                        delete(app.s_port);
                    end
                end
                app.s_port = [];
            end
        end

        % Drop down opening function: DropDown_5
        function DropDown_5Opening(app, event)
            tipo = strtrim(app.DropDown.Value);
            switch tipo
                case 'Filtro IIR'
                    app.DropDown_5.Items = {'Butterworth', 'Chebyshev'};
                case 'Filtro FIR'
                    app.DropDown_5.Items = {'Damped', 'LS'};
                otherwise
                    app.DropDown_5.Items = {'N/A'};
            end
        end

        % Button pushed function: AudiooriginalButton
        function AudiooriginalButtonPushed(app, event)
            if isempty(app.senal)
                uialert(app.UIFigure, 'No hay senal cargada', 'Sin senal');
                return;
            end
            if isempty(app.Fs) || app.Fs <= 0
                uialert(app.UIFigure, 'Frecuencia de muestreo no valida', 'Error');
                return;
            end
            try
                sound(app.senal, app.Fs);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error de audio');
            end
        end

        % Button pushed function: AudioprocesadoButton
        function AudioprocesadoButtonPushed(app, event)
            if isempty(app.senal_proc)
                uialert(app.UIFigure, 'No hay senal procesada', 'Sin senal');
                return;
            end
            if isempty(app.Fs) || app.Fs <= 0
                uialert(app.UIFigure, 'Frecuencia de muestreo no valida', 'Error');
                return;
            end
            try
                sound(app.senal_proc, app.Fs);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error de audio');
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            try
                clear sound
            catch ME
                uialert(app.UIFigure, ME.message, 'No hay nada reproduciéndose');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Senal Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Espectro Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Senal Procesada')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Resp. Frecuencia - Fase')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Resp. Frecuencia - Magnitud')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Espectro Procesado')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.SenaldeentradaPanel = uipanel(app.ControlesPanel);
            app.SenaldeentradaPanel.Title = 'Senal de entrada';
            app.CargarmatButton = uibutton(app.SenaldeentradaPanel, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar .mat';
            app.CapturarNucleoButton = uibutton(app.SenaldeentradaPanel, 'push');
            app.CapturarNucleoButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarNucleoButtonPushed, true);
            app.CapturarNucleoButton.Text = 'Capturar Nucleo';
            app.NmuestrasLabel = uilabel(app.SenaldeentradaPanel);
            app.NmuestrasLabel.Text = 'N muestras:';
            app.EditField = uieditfield(app.SenaldeentradaPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 1000;
            app.SinsenalcargadaLabel = uilabel(app.SenaldeentradaPanel);
            app.SinsenalcargadaLabel.Text = 'Sin senal cargada';
            app.PuertoserialPanel = uipanel(app.ControlesPanel);
            app.PuertoserialPanel.Title = 'Puerto serial';
            app.PuertoLabel = uilabel(app.PuertoserialPanel);
            app.PuertoLabel.Text = 'Puerto:';
            app.BaudLabel = uilabel(app.PuertoserialPanel);
            app.BaudLabel.Text = 'Baud:';
            app.EditField_2 = uieditfield(app.PuertoserialPanel, 'text');
            app.EditField_2.Value = 'COM3';
            app.EditField2 = uieditfield(app.PuertoserialPanel, 'numeric');
            app.EditField2.Limits = [110 1500000];
            app.EditField2.Value = 115200;
            app.FiltroEfectoPanel = uipanel(app.ControlesPanel);
            app.FiltroEfectoPanel.Title = 'Filtro / Efecto';
            app.TipoLabel = uilabel(app.FiltroEfectoPanel);
            app.TipoLabel.Text = 'Tipo:';
            app.ClaseLabel = uilabel(app.FiltroEfectoPanel);
            app.ClaseLabel.Text = 'Clase:';
            app.OrdenLabel = uilabel(app.FiltroEfectoPanel);
            app.OrdenLabel.Text = 'Orden:';
            app.fc1HzLabel = uilabel(app.FiltroEfectoPanel);
            app.fc1HzLabel.Text = 'fc1 (Hz):';
            app.DropDown = uidropdown(app.FiltroEfectoPanel);
            app.DropDown.Items = {'Filtro IIR ', 'Filtro FIR', 'Eco', 'Distorsion'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Filtro IIR ';
            app.DropDown2 = uidropdown(app.FiltroEfectoPanel);
            app.DropDown2.Items = {'LOWPASS', 'HIGHPASS', 'BANDPASS', 'BANDSTOP'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'LOWPASS';
            app.EditField_3 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField_3.Limits = [1 50];
            app.EditField_3.Value = 4;
            app.EditField2_2 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField2_2.Limits = [1 Inf];
            app.EditField2_2.Value = 100;
            app.fc2HzLabel = uilabel(app.FiltroEfectoPanel);
            app.fc2HzLabel.Text = 'fc2 (Hz):';
            app.DelaysLabel = uilabel(app.FiltroEfectoPanel);
            app.DelaysLabel.Text = 'Delay (s):';
            app.GananciaLabel = uilabel(app.FiltroEfectoPanel);
            app.GananciaLabel.Text = 'Ganancia:';
            app.RepeticionesLabel = uilabel(app.FiltroEfectoPanel);
            app.RepeticionesLabel.Text = 'Repeticiones:';
            app.UmbralLabel = uilabel(app.FiltroEfectoPanel);
            app.UmbralLabel.Text = 'Umbral:';
            app.EditField_4 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField_4.Limits = [0 100];
            app.EditField_4.Value = 1;
            app.EditField2_3 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField2_3.Limits = [1 20];
            app.EditField2_3.Value = 1;
            app.EditField3 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField3.Limits = [1 Inf];
            app.EditField3.Value = 500;
            app.EditField4 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField4.Limits = [0.001 5];
            app.EditField4.Value = 0.1;
            app.EditField5 = uieditfield(app.FiltroEfectoPanel, 'numeric');
            app.EditField5.Limits = [0.01 1];
            app.EditField5.Value = 0.01;
            app.APLICARButton = uibutton(app.FiltroEfectoPanel, 'push');
            app.APLICARButton.ButtonPushedFcn = createCallbackFcn(app, @APLICARButtonPushed, true);
            app.APLICARButton.Text = 'APLICAR';
            app.DropDown_5 = uidropdown(app.FiltroEfectoPanel);
            app.DropDown_5.Items = {'Butterworth', 'Chebyshev', 'Damped', 'LS'};
            app.DropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_5Opening, true);
            app.DropDown_5.Value = 'Butterworth';
            app.AproximacinLabel = uilabel(app.FiltroEfectoPanel);
            app.AproximacinLabel.Text = 'Aproximación';
            app.AudiooriginalButton = uibutton(app.FiltroEfectoPanel, 'push');
            app.AudiooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @AudiooriginalButtonPushed, true);
            app.AudiooriginalButton.Text = 'Audio original';
            app.AudioprocesadoButton = uibutton(app.FiltroEfectoPanel, 'push');
            app.AudioprocesadoButton.ButtonPushedFcn = createCallbackFcn(app, @AudioprocesadoButtonPushed, true);
            app.AudioprocesadoButton.Text = 'Audio procesado';
            app.STOPButton = uibutton(app.FiltroEfectoPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ExportarComunicacionPanel = uipanel(app.ControlesPanel);
            app.ExportarComunicacionPanel.Title = 'Exportar / Comunicacion';
            app.ExportarcsvButton = uibutton(app.ExportarComunicacionPanel, 'push');
            app.ExportarcsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarcsvButtonPushed, true);
            app.ExportarcsvButton.Text = 'Exportar .csv';
            app.ExportarmatButton = uibutton(app.ExportarComunicacionPanel, 'push');
            app.ExportarmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarmatButtonPushed, true);
            app.ExportarmatButton.Text = 'Exportar .mat';
            app.EnviarfiltroaNucleoButton = uibutton(app.ExportarComunicacionPanel, 'push');
            app.EnviarfiltroaNucleoButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarfiltroaNucleoButtonPushed, true);
            app.EnviarfiltroaNucleoButton.Text = 'Enviar filtro a Nucleo';
            app.OpcionesdeGraficasPanel = uipanel(app.ControlesPanel);
            app.OpcionesdeGraficasPanel.Title = 'Opciones de Graficas';
            app.MagnitudLabel = uilabel(app.OpcionesdeGraficasPanel);
            app.MagnitudLabel.Text = 'Magnitud:';
            app.EjefreqLabel = uilabel(app.OpcionesdeGraficasPanel);
            app.EjefreqLabel.Text = 'Eje freq:';
            app.EspectroLabel = uilabel(app.OpcionesdeGraficasPanel);
            app.EspectroLabel.Text = 'Espectro:';
            app.DropDown_2 = uidropdown(app.OpcionesdeGraficasPanel);
            app.DropDown_2.Items = {'dB', 'Lineal'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'dB';
            app.DropDown_3 = uidropdown(app.OpcionesdeGraficasPanel);
            app.DropDown_3.Items = {'Hz', 'rad/muestra'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_3.Value = 'Hz';
            app.DropDown_4 = uidropdown(app.OpcionesdeGraficasPanel);
            app.DropDown_4.Items = {'plot', 'stem'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_4.Value = 'plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FRONT_END

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end