classdef frontEND < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FiltradoPanel                  matlab.ui.container.Panel
        AplicarfiltroButton            matlab.ui.control.Button
        EscalaHorizontalDropDown       matlab.ui.control.DropDown
        EscalaHorizontalDropDownLabel  matlab.ui.control.Label
        TiempodemuestreosEditField     matlab.ui.control.NumericEditField
        TiempodemuestreosEditFieldLabel  matlab.ui.control.Label
        TipodefiltroDropDown           matlab.ui.control.DropDown
        TipodefiltroDropDownLabel      matlab.ui.control.Label
        SeleccindedatosPanel           matlab.ui.container.Panel
        LeerpuertoButton               matlab.ui.control.Button
        CargarArchivoButton            matlab.ui.control.Button
        OrigenDropDown                 matlab.ui.control.DropDown
        OrigenDropDownLabel            matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        signal_arch; % señal .mat
        Fs_arch; % frecuencia de muestreo archivo
        filter_type; % Tipo de filtro seleccionado por el usario
        xaxis_type; % Tipo de eje horizontal deseado
        T; % Periodo de muestreo deseado


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            % Abrir ventana para seleccionar archivo .mat
            [file, path] = uigetfile('*.mat', 'Seleccione el archivo ,mat');

             % Caso 1: Usuario cierra la ventana
            if isequal(file, 0)
                uialert(app.UIFigure, 'No se seleccionó ningún archivo.', 'Operación cancelada', 'Icon', 'warning');
                return;
            end


            % Caso 2: Usuario selecciona un archivo
            try
                fullPath = fullfile(path, file);
                data = load(fullPath);
            
                if isfield(data, 'x') && isfield(data, 'fs')
                    % Validar que x sea un numero y un vector
                    if isnumeric(data.x) && isvector(data.x)
                        app.signal_arch = data.x(:);        % Asegura vector columna
                        app.Fs_arch = data.fs;         % Frecuencia de muestreo
                        uialert(app.UIFigure, 'Datos cargados correctamente.', 'Éxito', 'Icon', 'success');
                        % Graficar señal original
                        signal = app.signal_arch;
                        plot(app.UIAxes, signal, 'b');
                        title(app.UIAxes, 'Señal Original');
                        xlabel(app.UIAxes, 'Muestras');
                        ylabel(app.UIAxes, 'Amplitud');
                        grid(app.UIAxes, 'on');
                    else
                        uialert(app.UIFigure, 'La variable "x" debe ser un vector numérico.', 'Error de formato', 'Icon', 'error');
                    end
                else
                    uialert(app.UIFigure, 'El archivo no contiene las variables "x" y "fs".', 'Error', 'Icon', 'error');
                end
            catch ME
                uialert(app.UIFigure, sprintf('Error al cargar el archivo: %s', ME.message), 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: AplicarfiltroButton
        function AplicarfiltroButtonPushed(app, event)
           
            x = app.signal_arch;
            fs = app.Fs_arch;

            % Graficar señal filtrada
            
            plot(app.UIAxes_2, signal_filter, 'b');
            title(app.UIAxes_2, 'Señal Filtrada');
            xlabel(app.UIAxes_2, 'Muestras');
            ylabel(app.UIAxes_2, 'Amplitud');
            grid(app.UIAxes_2, 'on');

            % Graficar respuesta en frecuencia

            [H,f_Hz] = freqz(numerador,denominador,500,fs);

            plot(app.UIAxes_3, f_Hz, abs(H), 'b');
            title(app.UIAxes_3, 'Respuesta en Frecuencia');
            xlabel(app.UIAxes_3, 'Hz');
            ylabel(app.UIAxes_3, 'Amplitud');
            ylim(app.UIAxes_3,[0 1.2])
            grid(app.UIAxes_3, 'on');
        end

        % Drop down opening function: TipodefiltroDropDown
        function TipodefiltroDropDownOpening(app, event)
           
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
            app.filter_type = app.TipodefiltroDropDown.Value;
            
        end

        % Value changed function: EscalaHorizontalDropDown
        function EscalaHorizontalDropDownValueChanged(app, event)
            app.xaxis_type = app.EscalaHorizontalDropDown.Value;
            
        end

        % Value changed function: TiempodemuestreosEditField
        function TiempodemuestreosEditFieldValueChanged(app, event)
            app.T = app.TiempodemuestreosEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.Tab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SeleccindedatosPanel = uipanel(app.UIFigure);
            app.SeleccindedatosPanel.Title = 'Selección de datos';
            app.OrigenDropDownLabel = uilabel(app.SeleccindedatosPanel);
            app.OrigenDropDownLabel.Text = 'Origen';
            app.OrigenDropDown = uidropdown(app.SeleccindedatosPanel);
            app.OrigenDropDown.Items = {'Archivo.mat', 'COM', 'Audio.waw'};
            app.OrigenDropDown.Value = 'Archivo.mat';
            app.CargarArchivoButton = uibutton(app.SeleccindedatosPanel, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.LeerpuertoButton = uibutton(app.SeleccindedatosPanel, 'push');
            app.LeerpuertoButton.Text = 'Leer puerto';
            app.FiltradoPanel = uipanel(app.UIFigure);
            app.FiltradoPanel.Title = 'Filtrado';
            app.TipodefiltroDropDownLabel = uilabel(app.FiltradoPanel);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.TipodefiltroDropDown = uidropdown(app.FiltradoPanel);
            app.TipodefiltroDropDown.Items = {'Pasa bajas', 'Pasa altas'};
            app.TipodefiltroDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TipodefiltroDropDownOpening, true);
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'Pasa bajas';
            app.TiempodemuestreosEditFieldLabel = uilabel(app.FiltradoPanel);
            app.TiempodemuestreosEditFieldLabel.Text = 'Tiempo de muestreo (s)';
            app.TiempodemuestreosEditField = uieditfield(app.FiltradoPanel, 'numeric');
            app.TiempodemuestreosEditField.Limits = [0 10];
            app.TiempodemuestreosEditField.ValueChangedFcn = createCallbackFcn(app, @TiempodemuestreosEditFieldValueChanged, true);
            app.EscalaHorizontalDropDownLabel = uilabel(app.FiltradoPanel);
            app.EscalaHorizontalDropDownLabel.Text = 'Escala Horizontal';
            app.EscalaHorizontalDropDown = uidropdown(app.FiltradoPanel);
            app.EscalaHorizontalDropDown.Items = {'Hz', 'Rad / muestra'};
            app.EscalaHorizontalDropDown.ValueChangedFcn = createCallbackFcn(app, @EscalaHorizontalDropDownValueChanged, true);
            app.EscalaHorizontalDropDown.Value = 'Hz';
            app.AplicarfiltroButton = uibutton(app.FiltradoPanel, 'push');
            app.AplicarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarfiltroButtonPushed, true);
            app.AplicarfiltroButton.Text = 'Aplicar filtro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frontEND

            % Associate the Simulink Model
            app.Simulation = simulation('Bloques_basicos');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end